classdef heyohletsgo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SonohiLTESimulatorUIFigure      matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SimulationSetupTab              matlab.ui.container.Tab
        ResetthefolderstructureandreloadeverythingSwitchLabel  matlab.ui.control.Label
        ResetthefolderstructureandreloadeverythingSwitch  matlab.ui.control.Switch
        CleantheresultsfolderSwitchLabel  matlab.ui.control.Label
        CleantheresultsfolderSwitch     matlab.ui.control.Switch
        EnabledrawingofplotsnetworkdiagramsetcSwitchLabel  matlab.ui.control.Label
        EnabledrawingofplotsnetworkdiagramsetcSwitch  matlab.ui.control.Switch
        StorethetransmissiondatathatiseachTBcodewordwaveformSwitchLabel  matlab.ui.control.Label
        StorethetransmissiondatathatiseachTBcodewordwaveformSwitch  matlab.ui.control.Switch
        Label                           matlab.ui.control.Label
        NumberofschedulingroundtosimulateineachsimulationEditFieldLabel  matlab.ui.control.Label
        NumberofschedulingroundtosimulateineachsimulationEditField  matlab.ui.control.NumericEditField
        SimulationseedEditFieldLabel    matlab.ui.control.Label
        SimulationseedEditField         matlab.ui.control.NumericEditField
        SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitchLabel  matlab.ui.control.Label
        SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitch  matlab.ui.control.Switch
        GenerateaheatmapforthenetworkscenarioSwitchLabel  matlab.ui.control.Label
        GenerateaheatmapforthenetworkscenarioSwitch  matlab.ui.control.Switch
        TypeofheatmapDropDownLabel      matlab.ui.control.Label
        TypeofheatmapDropDown           matlab.ui.control.DropDown
        HeatmapresolutioninmetresEditFieldLabel  matlab.ui.control.Label
        HeatmapresolutioninmetresEditField  matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        NetworkSetupTab                 matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        NumberofResourceBlocksforamacroeNodeBEditFieldLabel  matlab.ui.control.Label
        NumberofResourceBlocksforamacroeNodeBEditField  matlab.ui.control.NumericEditField
        NumberofResourceBlocksforamicroeNodeBEditFieldLabel  matlab.ui.control.Label
        NumberofResourceBlocksforamicroeNodeBEditField  matlab.ui.control.NumericEditField
        NumberofResourceBlocksforUEsEditFieldLabel  matlab.ui.control.Label
        NumberofResourceBlocksforUEsEditField  matlab.ui.control.NumericEditField
        NumberofMacroeNodeBsEditFieldLabel  matlab.ui.control.Label
        NumberofMacroeNodeBsEditField   matlab.ui.control.NumericEditField
        NumberofMicroeNodeBsEditFieldLabel  matlab.ui.control.Label
        NumberofMicroeNodeBsEditField   matlab.ui.control.NumericEditField
        NumberofUEsEditFieldLabel       matlab.ui.control.Label
        NumberofUEsEditField            matlab.ui.control.NumericEditField
        HeightofMacroeNodeBsinmetresEditFieldLabel  matlab.ui.control.Label
        HeightofMacroeNodeBsinmetresEditField  matlab.ui.control.NumericEditField
        HeightofMicroeNodeBsinmetresEditFieldLabel  matlab.ui.control.Label
        HeightofMicroeNodeBsinmetresEditField  matlab.ui.control.NumericEditField
        HeightofUEsinmetresEditFieldLabel  matlab.ui.control.Label
        HeightofUEsinmetresEditField    matlab.ui.control.NumericEditField
        PositioningmethodforMicroeNodeBsDropDownLabel  matlab.ui.control.Label
        PositioningmethodforMicroeNodeBsDropDown  matlab.ui.control.DropDown
        RadiusfromcentreofnetworkforuniformMicroeNodeBspositioningLabel  matlab.ui.control.Label
        HeightofUEsinmetresEditField_2  matlab.ui.control.NumericEditField
        MobilityscenariofortheUEsinthenetworkDropDownLabel  matlab.ui.control.Label
        MobilityscenariofortheUEsinthenetworkDropDown  matlab.ui.control.DropDown
        ApplicationtrafficmodelDropDownLabel  matlab.ui.control.Label
        ApplicationtrafficmodelDropDown  matlab.ui.control.DropDown
        EditFieldLabel                  matlab.ui.control.Label
        PathforloadingthetextfilewiththebuildingsinformationEditField  matlab.ui.control.EditField
        PhysicallayersetupTab           matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        SchedulerTab                    matlab.ui.container.Tab
        ChannelTab                      matlab.ui.container.Tab
        SONTab                          matlab.ui.container.Tab
        UtilisationthresholdforswitchingoffeNodeBsEditFieldLabel  matlab.ui.control.Label
        UtilisationthresholdforswitchingoffeNodeBsEditField  matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        RunTab                          matlab.ui.container.Tab
        RunButton                       matlab.ui.control.StateButton
        Label_6                         matlab.ui.control.Label
    end


    properties (Access = private)
        SimParam % Parameters structure
        SimTraffic % Traffic source
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initParam;
            load 'SimulationParameters' 'Param';
            app.SimParam = Param;
            switchMap = {'Off', 'On'};
            % Simulation
            app.ResetthefolderstructureandreloadeverythingSwitch.Tag = 'reset';
            app.ResetthefolderstructureandreloadeverythingSwitch.Value = switchMap{Param.reset + 1};
            app.CleantheresultsfolderSwitch.Tag = 'rmResults';
            app.CleantheresultsfolderSwitch.Value = switchMap{Param.rmResults + 1};
            app.EnabledrawingofplotsnetworkdiagramsetcSwitch.Tag = 'draw';
            app.EnabledrawingofplotsnetworkdiagramsetcSwitch.Value = switchMap{Param.draw + 1};
            app.StorethetransmissiondatathatiseachTBcodewordwaveformSwitch.Tag = 'storeTxData';
            app.StorethetransmissiondatathatiseachTBcodewordwaveformSwitch.Value = switchMap{Param.storeTxData + 1};
            app.NumberofschedulingroundtosimulateineachsimulationEditField.Tag = 'schRounds';
            app.NumberofschedulingroundtosimulateineachsimulationEditField.Value = Param.schRounds;
            app.SimulationseedEditField.Tag = 'seed';
            app.SimulationseedEditField.Value = Param.seed;
            app.SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitch.Tag = 'saveFrame';
            app.SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitch.Value = switchMap{Param.saveFrame + 1};
            app.GenerateaheatmapforthenetworkscenarioSwitch.Tag = 'generateHeatMap';
            app.GenerateaheatmapforthenetworkscenarioSwitch.Value = Param.generateHeatMap;
            app.TypeofheatmapDropDown.Tag = 'heatMapType';
            app.TypeofheatmapDropDown.Value = Param.heatMapType;
            app.HeatmapresolutioninmetresEditField.Tag = 'heatMapRes';
            app.HeatmapresolutioninmetresEditField.Value = Param.heatMapRes;            
            
            % Network
            
            
            % Physical layer
            
            
        end

        % Callback function: ApplicationtrafficmodelDropDown, 
        % HeatmapresolutioninmetresEditField, 
        % HeightofMacroeNodeBsinmetresEditField, 
        % HeightofMicroeNodeBsinmetresEditField, 
        % HeightofUEsinmetresEditField, HeightofUEsinmetresEditField_2, 
        % MobilityscenariofortheUEsinthenetworkDropDown, 
        % NumberofMacroeNodeBsEditField, NumberofMicroeNodeBsEditField, 
        % NumberofResourceBlocksforUEsEditField, 
        % NumberofResourceBlocksforamacroeNodeBEditField, 
        % NumberofResourceBlocksforamicroeNodeBEditField, 
        % NumberofUEsEditField, 
        % NumberofschedulingroundtosimulateineachsimulationEditField, 
        % PathforloadingthetextfilewiththebuildingsinformationEditField, 
        % PositioningmethodforMicroeNodeBsDropDown, 
        % SimulationseedEditField, TypeofheatmapDropDown
        function setParamValue(app, event)
            app.SimParam.(event.Source.Tag) = event.Value;
        end

        % Callback function: CleantheresultsfolderSwitch, 
        % EnabledrawingofplotsnetworkdiagramsetcSwitch, 
        % GenerateaheatmapforthenetworkscenarioSwitch, 
        % ResetthefolderstructureandreloadeverythingSwitch, 
        % SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitch, 
        % StorethetransmissiondatathatiseachTBcodewordwaveformSwitch
        function setParamBooleanValue(app, event)
            value = 0;
            if event.Value == 'On'
                value = 1;
            end
            app.SimParam.(event.Source.Tag) = value;
            
            
        end

        % Value changed function: RunButton
        function saveParamAndRun(app, event)
            app.simParam.buildings = load(app.simParam.buildings);
            app.simParam.buildingHeight = [20,50];
            app.simParam.area = [min(app.simParam.buildings(:, 1)), min(app.simParam.buildings(:, 2)), ...
            	max(app.simParam.buildings(:, 3)), max(app.simParam.buildings(:, 4))];
            app.simParam.buildings(:,5) = randi([app.simParam.buildingHeight],[1 length(app.simParam.buildings(:,1))]);
            
            app.simParam.harq.tout = app.simParam.harq.proc/2 -1;
            
            % Get traffic source data and check if we have already the MAT file with the traffic data
            switch app.simParam.trafficModel
            	case 'videoStreaming'
            	   if (exist('traffic/videoStreaming.mat', 'file') ~= 2 || app.simParam.reset)
            	       app.SimTraffic = loadVideoStreamingTraffic('traffic/videoStreaming.csv', true);
            	   else
            	       load 'traffic/videoStreaming.mat' 'trSource';
                       app.SimTraffic = trSource;
            	   end
            	case 'fullBuffer'
            	   if (exist('traffic/fullBuffer.mat', 'file') ~= 2 || Param.reset)
            	       app.SimTraffic = loadFullBufferTraffic('traffic/fullBuffer.csv');
            	   else
            	       load 'traffic/fullBuffer.mat' 'trSource';
                       app.SimTraffic = trSource;
            	   end
            end
            Param = app.SimParam;
            trSource = app.SimTraffic;
            save(strcat('SimulationParameters.mat'), 'Param', 'trSource');
            
            % RUN!!!!
            main;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SonohiLTESimulatorUIFigure = uifigure;
            app.SonohiLTESimulatorUIFigure.Name = 'Sonohi LTE Simulator';
            app.TabGroup = uitabgroup(app.SonohiLTESimulatorUIFigure);
            app.SimulationSetupTab = uitab(app.TabGroup);
            app.SimulationSetupTab.Title = 'Simulation Setup';
            app.ResetthefolderstructureandreloadeverythingSwitchLabel = uilabel(app.SimulationSetupTab);
            app.ResetthefolderstructureandreloadeverythingSwitchLabel.Text = 'Reset the folder structure and reload everything';
            app.ResetthefolderstructureandreloadeverythingSwitch = uiswitch(app.SimulationSetupTab, 'slider');
            app.ResetthefolderstructureandreloadeverythingSwitch.ValueChangedFcn = createCallbackFcn(app, @setParamBooleanValue, true);
            app.CleantheresultsfolderSwitchLabel = uilabel(app.SimulationSetupTab);
            app.CleantheresultsfolderSwitchLabel.Text = 'Clean the results folder';
            app.CleantheresultsfolderSwitch = uiswitch(app.SimulationSetupTab, 'slider');
            app.CleantheresultsfolderSwitch.ValueChangedFcn = createCallbackFcn(app, @setParamBooleanValue, true);
            app.CleantheresultsfolderSwitch.Value = 'On';
            app.EnabledrawingofplotsnetworkdiagramsetcSwitchLabel = uilabel(app.SimulationSetupTab);
            app.EnabledrawingofplotsnetworkdiagramsetcSwitchLabel.Text = 'Enable drawing of plots, network diagrams, etc. ';
            app.EnabledrawingofplotsnetworkdiagramsetcSwitch = uiswitch(app.SimulationSetupTab, 'slider');
            app.EnabledrawingofplotsnetworkdiagramsetcSwitch.ValueChangedFcn = createCallbackFcn(app, @setParamBooleanValue, true);
            app.StorethetransmissiondatathatiseachTBcodewordwaveformSwitchLabel = uilabel(app.SimulationSetupTab);
            app.StorethetransmissiondatathatiseachTBcodewordwaveformSwitchLabel.Text = 'Store the transmission data, that is each TB, codeword, waveform';
            app.StorethetransmissiondatathatiseachTBcodewordwaveformSwitch = uiswitch(app.SimulationSetupTab, 'slider');
            app.StorethetransmissiondatathatiseachTBcodewordwaveformSwitch.ValueChangedFcn = createCallbackFcn(app, @setParamBooleanValue, true);
            app.Label = uilabel(app.SimulationSetupTab);
            app.Label.Text = 'The parameters in this tab can be used to setup overall simulation parameters';
            app.NumberofschedulingroundtosimulateineachsimulationEditFieldLabel = uilabel(app.SimulationSetupTab);
            app.NumberofschedulingroundtosimulateineachsimulationEditFieldLabel.Text = 'Number of scheduling round to simulate in each simulation';
            app.NumberofschedulingroundtosimulateineachsimulationEditField = uieditfield(app.SimulationSetupTab, 'numeric');
            app.NumberofschedulingroundtosimulateineachsimulationEditField.Limits = [1 20000];
            app.NumberofschedulingroundtosimulateineachsimulationEditField.ValueDisplayFormat = '%.0f';
            app.NumberofschedulingroundtosimulateineachsimulationEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NumberofschedulingroundtosimulateineachsimulationEditField.Value = 5;
            app.SimulationseedEditFieldLabel = uilabel(app.SimulationSetupTab);
            app.SimulationseedEditFieldLabel.Text = 'Simulation seed';
            app.SimulationseedEditField = uieditfield(app.SimulationSetupTab, 'numeric');
            app.SimulationseedEditField.Limits = [1 Inf];
            app.SimulationseedEditField.ValueDisplayFormat = '%.0f';
            app.SimulationseedEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.SimulationseedEditField.Value = 42;
            app.SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitchLabel = uilabel(app.SimulationSetupTab);
            app.SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitchLabel.Text = 'Save a whole LTE frame for the macro eNodeB (primarily for debug)';
            app.SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitch = uiswitch(app.SimulationSetupTab, 'slider');
            app.SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitch.ValueChangedFcn = createCallbackFcn(app, @setParamBooleanValue, true);
            app.SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitch.Value = 'On';
            app.GenerateaheatmapforthenetworkscenarioSwitchLabel = uilabel(app.SimulationSetupTab);
            app.GenerateaheatmapforthenetworkscenarioSwitchLabel.Text = 'Generate a heatmap for the network scenario';
            app.GenerateaheatmapforthenetworkscenarioSwitch = uiswitch(app.SimulationSetupTab, 'slider');
            app.GenerateaheatmapforthenetworkscenarioSwitch.ValueChangedFcn = createCallbackFcn(app, @setParamBooleanValue, true);
            app.GenerateaheatmapforthenetworkscenarioSwitch.Value = 'On';
            app.TypeofheatmapDropDownLabel = uilabel(app.SimulationSetupTab);
            app.TypeofheatmapDropDownLabel.Text = 'Type of heatmap';
            app.TypeofheatmapDropDown = uidropdown(app.SimulationSetupTab);
            app.TypeofheatmapDropDown.Items = {'perStation', 'perClass'};
            app.TypeofheatmapDropDown.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.TypeofheatmapDropDown.Value = 'perStation';
            app.HeatmapresolutioninmetresEditFieldLabel = uilabel(app.SimulationSetupTab);
            app.HeatmapresolutioninmetresEditFieldLabel.Text = 'Heatmap resolution in metres';
            app.HeatmapresolutioninmetresEditField = uieditfield(app.SimulationSetupTab, 'numeric');
            app.HeatmapresolutioninmetresEditField.Limits = [5 100];
            app.HeatmapresolutioninmetresEditField.ValueDisplayFormat = '%.2f';
            app.HeatmapresolutioninmetresEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.HeatmapresolutioninmetresEditField.Value = 10;
            app.Label_4 = uilabel(app.SimulationSetupTab);
            app.Label_4.Text = 'Heatmap parameters';
            app.NetworkSetupTab = uitab(app.TabGroup);
            app.NetworkSetupTab.Title = 'Network Setup';
            app.Label_2 = uilabel(app.NetworkSetupTab);
            app.Label_2.Text = 'The parameters in this tab can be used to setup the network';
            app.NumberofResourceBlocksforamacroeNodeBEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.NumberofResourceBlocksforamacroeNodeBEditFieldLabel.Text = 'Number of Resource Blocks for a macro eNodeB';
            app.NumberofResourceBlocksforamacroeNodeBEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.NumberofResourceBlocksforamacroeNodeBEditField.Limits = [1 100];
            app.NumberofResourceBlocksforamacroeNodeBEditField.ValueDisplayFormat = '%.0f';
            app.NumberofResourceBlocksforamacroeNodeBEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NumberofResourceBlocksforamacroeNodeBEditField.Value = 50;
            app.NumberofResourceBlocksforamicroeNodeBEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.NumberofResourceBlocksforamicroeNodeBEditFieldLabel.Text = 'Number of Resource Blocks for a micro eNodeB';
            app.NumberofResourceBlocksforamicroeNodeBEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.NumberofResourceBlocksforamicroeNodeBEditField.Limits = [6 100];
            app.NumberofResourceBlocksforamicroeNodeBEditField.ValueDisplayFormat = '%.0f';
            app.NumberofResourceBlocksforamicroeNodeBEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NumberofResourceBlocksforamicroeNodeBEditField.Value = 25;
            app.NumberofResourceBlocksforUEsEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.NumberofResourceBlocksforUEsEditFieldLabel.Text = 'Number of Resource Blocks for UEs';
            app.NumberofResourceBlocksforUEsEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.NumberofResourceBlocksforUEsEditField.Limits = [6 100];
            app.NumberofResourceBlocksforUEsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofResourceBlocksforUEsEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NumberofResourceBlocksforUEsEditField.Value = 25;
            app.NumberofMacroeNodeBsEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.NumberofMacroeNodeBsEditFieldLabel.Text = 'Number of Macro eNodeBs';
            app.NumberofMacroeNodeBsEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.NumberofMacroeNodeBsEditField.Limits = [1 2];
            app.NumberofMacroeNodeBsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofMacroeNodeBsEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NumberofMacroeNodeBsEditField.Editable = 'off';
            app.NumberofMacroeNodeBsEditField.Value = 1;
            app.NumberofMicroeNodeBsEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.NumberofMicroeNodeBsEditFieldLabel.Text = 'Number of Micro eNodeBs';
            app.NumberofMicroeNodeBsEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.NumberofMicroeNodeBsEditField.Limits = [1 10];
            app.NumberofMicroeNodeBsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofMicroeNodeBsEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NumberofMicroeNodeBsEditField.Value = 2;
            app.NumberofUEsEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.NumberofUEsEditFieldLabel.Text = 'Number of UEs';
            app.NumberofUEsEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.NumberofUEsEditField.Limits = [1 50];
            app.NumberofUEsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofUEsEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NumberofUEsEditField.Value = 2;
            app.HeightofMacroeNodeBsinmetresEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.HeightofMacroeNodeBsinmetresEditFieldLabel.Text = 'Height of Macro eNodeBs in metres';
            app.HeightofMacroeNodeBsinmetresEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.HeightofMacroeNodeBsinmetresEditField.Limits = [1 100];
            app.HeightofMacroeNodeBsinmetresEditField.ValueDisplayFormat = '%.2f';
            app.HeightofMacroeNodeBsinmetresEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.HeightofMacroeNodeBsinmetresEditField.Value = 35;
            app.HeightofMicroeNodeBsinmetresEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.HeightofMicroeNodeBsinmetresEditFieldLabel.Text = 'Height of Micro eNodeBs in metres';
            app.HeightofMicroeNodeBsinmetresEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.HeightofMicroeNodeBsinmetresEditField.Limits = [1 100];
            app.HeightofMicroeNodeBsinmetresEditField.ValueDisplayFormat = '%.2f';
            app.HeightofMicroeNodeBsinmetresEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.HeightofMicroeNodeBsinmetresEditField.Value = 25;
            app.HeightofUEsinmetresEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.HeightofUEsinmetresEditFieldLabel.Text = 'Height of UEs in metres';
            app.HeightofUEsinmetresEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.HeightofUEsinmetresEditField.Limits = [0 3];
            app.HeightofUEsinmetresEditField.ValueDisplayFormat = '%.2f';
            app.HeightofUEsinmetresEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.HeightofUEsinmetresEditField.Value = 1.5;
            app.PositioningmethodforMicroeNodeBsDropDownLabel = uilabel(app.NetworkSetupTab);
            app.PositioningmethodforMicroeNodeBsDropDownLabel.Text = 'Positioning method for Micro eNodeBs';
            app.PositioningmethodforMicroeNodeBsDropDown = uidropdown(app.NetworkSetupTab);
            app.PositioningmethodforMicroeNodeBsDropDown.Items = {'uniform', 'random'};
            app.PositioningmethodforMicroeNodeBsDropDown.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.PositioningmethodforMicroeNodeBsDropDown.Value = 'uniform';
            app.RadiusfromcentreofnetworkforuniformMicroeNodeBspositioningLabel = uilabel(app.NetworkSetupTab);
            app.RadiusfromcentreofnetworkforuniformMicroeNodeBspositioningLabel.Text = 'Radius in metres from centre of network for uniform Micro eNodeBs positioning';
            app.HeightofUEsinmetresEditField_2 = uieditfield(app.NetworkSetupTab, 'numeric');
            app.HeightofUEsinmetresEditField_2.Limits = [10 200];
            app.HeightofUEsinmetresEditField_2.ValueDisplayFormat = '%.2f';
            app.HeightofUEsinmetresEditField_2.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.HeightofUEsinmetresEditField_2.Value = 100;
            app.MobilityscenariofortheUEsinthenetworkDropDownLabel = uilabel(app.NetworkSetupTab);
            app.MobilityscenariofortheUEsinthenetworkDropDownLabel.Text = 'Mobility scenario for the UEs in the network';
            app.MobilityscenariofortheUEsinthenetworkDropDown = uidropdown(app.NetworkSetupTab);
            app.MobilityscenariofortheUEsinthenetworkDropDown.Items = {'static', 'pedestrian', 'vehicular', 'superman'};
            app.MobilityscenariofortheUEsinthenetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.MobilityscenariofortheUEsinthenetworkDropDown.Value = 'static';
            app.ApplicationtrafficmodelDropDownLabel = uilabel(app.NetworkSetupTab);
            app.ApplicationtrafficmodelDropDownLabel.Text = 'Application traffic model';
            app.ApplicationtrafficmodelDropDown = uidropdown(app.NetworkSetupTab);
            app.ApplicationtrafficmodelDropDown.Items = {'fullBuffer', 'videoStreaming'};
            app.ApplicationtrafficmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.ApplicationtrafficmodelDropDown.Value = 'fullBuffer';
            app.EditFieldLabel = uilabel(app.NetworkSetupTab);
            app.EditFieldLabel.Text = 'Path for loading the text file with the buildings information';
            app.PathforloadingthetextfilewiththebuildingsinformationEditField = uieditfield(app.NetworkSetupTab, 'text');
            app.PathforloadingthetextfilewiththebuildingsinformationEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.PathforloadingthetextfilewiththebuildingsinformationEditField.Value = 'mobility/buildings.txt';
            app.PhysicallayersetupTab = uitab(app.TabGroup);
            app.PhysicallayersetupTab.Title = 'Physical layer setup';
            app.Label_5 = uilabel(app.PhysicallayersetupTab);
            app.Label_5.Text = 'The parameters in this tab can be used to setup the physical layer';
            app.SchedulerTab = uitab(app.TabGroup);
            app.SchedulerTab.Title = 'Scheduler';
            app.ChannelTab = uitab(app.TabGroup);
            app.ChannelTab.Title = 'Channel';
            app.SONTab = uitab(app.TabGroup);
            app.SONTab.Title = 'SON';
            app.UtilisationthresholdforswitchingoffeNodeBsEditFieldLabel = uilabel(app.SONTab);
            app.UtilisationthresholdforswitchingoffeNodeBsEditFieldLabel.Text = 'Utilisation threshold for switching off eNodeBs ';
            app.UtilisationthresholdforswitchingoffeNodeBsEditField = uieditfield(app.SONTab, 'numeric');
            app.UtilisationthresholdforswitchingoffeNodeBsEditField.Limits = [1 50];
            app.UtilisationthresholdforswitchingoffeNodeBsEditField.ValueDisplayFormat = '%.0f';
            app.UtilisationthresholdforswitchingoffeNodeBsEditField.Editable = 'off';
            app.UtilisationthresholdforswitchingoffeNodeBsEditField.Value = 2;
            app.Label_3 = uilabel(app.SONTab);
            app.Label_3.Text = 'The parameters in this tab can be used to configure power saving and SON features';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.RunButton = uibutton(app.RunTab, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @saveParamAndRun, true);
            app.RunButton.Text = 'Run';
            app.Label_6 = uilabel(app.RunTab);
            app.Label_6.Text = 'Run the simulation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = heyohletsgo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SonohiLTESimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SonohiLTESimulatorUIFigure)
        end
    end
end